classdef ESTrainer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RecordButton                 matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        ListenRecordedButton         matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        CalculateEmotionButton       matlab.ui.control.Button
        filenameEditFieldLabel       matlab.ui.control.Label
        filenameEditField            matlab.ui.control.EditField
        EmotionalSpeechTrainerLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        SpeechToTextButton           matlab.ui.control.Button
    end


    properties (Access = public)
        aud; % temporary audio file
        voice=audiorecorder(44100,16,1); %recorder object
        speechObject;
        featureVector=zeros(1,36); %initial feature vector
        featureTable
        classifier
        label
        cam=webcam;
        video=uint8(zeros(480,640,3,100));
    end

    methods (Access = public)
    
        function recorderbut(app)
            record(app.voice)
            
        end
        
        function recordvid(app)
            vd=uint8(zeros(480,640,3,100));
            for i=1:60
               img=snapshot(app.cam);
               %img=rgb2gray(img);
               vd(:,:,:,i)=uint8(img);
               pause(1/6)
               
            end
            app.video=vd;
            
        end
        
        function stopperbut(app)
            stop(app.voice)
            app.aud=getaudiodata(app.voice);            
        end
    
        function speechtotext(app)
%             outInfo=speech2text(app.speechObject, app.aud, 44100);
            outInfo=speech2text(app.speechObject, app.aud, 44100);
            app.EditField.Value=outInfo.TRANSCRIPT;
        end
        
        function playerbut(app)
            %play(app.voice)
            sound(app.aud,44100);
            %audiowrite(sprintf('%s.wav',app.filenameEditField.Value),app.aud, 44100);
        end
        
        function playvid(app)
            implay(app.video,6);
        end
        
        function savebut(app)

            audiowrite(sprintf('%s.wav',app.filenameEditField.Value),app.aud,44100);
        end
        function savetext(app)

            dlmwrite(sprintf('%s.txt',app.filenameEditField.Value),char(app.EditField.Value));
        end
        
        function savevid(app)
            v = VideoWriter(sprintf('%s.avi',app.filenameEditField.Value));
            v.FrameRate=6;
            open(v)
            writeVideo(v,app.video);
            close(v)       
        end
        
        function featureExtraction(app)
            a=app.aud;
            a=a*0.707/max(abs(a));
            miraud=miraudio(a);
            mfccs=mirgetdata(mirmfcc(miraud));
%             app.featureVector(1)=length(mirgetdata(mirpeaks(miraud)));
%             app.featureVector(2)=mirgetdata(mirrms(miraud));
            app.featureVector(1)=mirgetdata(mirlowenergy(miraud));
            app.featureVector(2)=mirgetdata(mireventdensity(miraud));
            app.featureVector(3)=mirgetdata(mirtempo(miraud));
            %valence kanonika sto 6
            app.featureVector(4)=mirgetdata(mirpulseclarity(miraud));
            app.featureVector(5)=mirgetdata(mirzerocross(miraud));
            app.featureVector(6)=mirgetdata(mirrolloff(miraud,'Threshold',0.3));
            app.featureVector(7)=mirgetdata(mirrolloff(miraud,'Threshold',0.5));
            %activity
            app.featureVector(8)=mirgetdata(mirrolloff(miraud,'Threshold',0.7));
            %tension
            app.featureVector(9)=mirgetdata(mirrolloff(miraud,'Threshold',0.9));
            app.featureVector(10)=mirgetdata(mirbrightness(miraud,'CutOff',500));
            app.featureVector(11)=mirgetdata(mirbrightness(miraud,'CutOff',1000));
            app.featureVector(12)=mirgetdata(mirbrightness(miraud,'CutOff',1500));
            app.featureVector(13)=mirgetdata(mirbrightness(miraud,'CutOff',2000));
            app.featureVector(14)=mirgetdata(mirbrightness(miraud,'CutOff',3000));
            app.featureVector(15)=mirgetdata(mirbrightness(miraud,'CutOff',4000));
%             app.featureVector(18)=
%             app.featureVector(19)=
%             app.featureVector(20)=
            app.featureVector(16)=mirgetdata(mirbrightness(miraud,'CutOff',8000));
            app.featureVector(17)=mirgetdata(mirmode(miraud));
            app.featureVector(18)=mirgetdata(mircentroid(miraud));
            app.featureVector(19)=mirgetdata(mirspread(miraud));
            app.featureVector(20)=mirgetdata(mirskewness(miraud));
            app.featureVector(21)=mirgetdata(mirkurtosis(miraud));
            app.featureVector(22)=mirgetdata(mirflatness(miraud));
            app.featureVector(23)=mirgetdata(mirentropy(miraud));
            app.featureVector(24)=mfccs(1);
            app.featureVector(25)=mfccs(2);
            app.featureVector(26)=mfccs(3);
            app.featureVector(27)=mfccs(4);
            app.featureVector(28)=mfccs(5);
            app.featureVector(29)=mfccs(6);
            app.featureVector(30)=mfccs(7);
            app.featureVector(31)=mfccs(8);
            app.featureVector(32)=mfccs(9);
            app.featureVector(33)=mfccs(10);
            app.featureVector(34)=mfccs(11);
            app.featureVector(35)=mfccs(12);
            app.featureVector(36)=mfccs(13);
            
            
            app.featureTable=array2table(app.featureVector);
            app.featureTable.Properties.VariableNames={'lowenergy','event_density','tempo','pulseclarity','zerocross','rolloff30','rolloff50','rolloff70','rolloff90','bright500','bright1000','bright1500','bright2000','bright3000','bright4000','bright8000','mode','centroid','spread	','skewness','kourtosis','flatness','entropy','mffc1','mfcc2','mffc3','mfcc4','mffc5','mfcc6','mffc7','mfcc8','mffc9','mfcc10','mffc11','mfcc12','mffc13'};
            app.label=app.classifier.predictFcn(app.featureTable);
            app.filenameEditField.Value=char(app.label);
            %app.filenameEditField.Value=num2str(app.featureVector(17));
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.voice=audiorecorder(44100,16,1);
            m=load('matlab.mat');
            app.classifier=m.trainedModel;
            app.speechObject= speechClient('IBM','keywords',"example,keywords",'keywords_threshold',0.5);
            
                   
        end

        % Button pushed function: CalculateEmotionButton
        function CalculateEmotionButtonPushed(app, event)
            featureExtraction(app);
        end

        % Button pushed function: ListenRecordedButton
        function ListenRecordedButtonPushed(app, event)
            
            app.StopButton.Enable='on';
            %play(app.voice);
            playerbut(app);
        end

        % Callback function
        function PlayVideoButtonPushed(app, event)
            playvid(app);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.StopButton.Enable='on';
            app.RecordButton.Enable='off';
            app.ListenRecordedButton.Enable='off';
            app.Lamp.Enable='on';
            app.SaveButton.Enable='off';
            %record(app.voice,5);
            recorderbut(app);
            preview(app.cam);
            recordvid(app)
            
        
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            savebut(app)
            savevid(app)
            savetext(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable='off';
            app.RecordButton.Enable='on';
            app.ListenRecordedButton.Enable='on';
            app.Lamp.Enable='off';
            app.SaveButton.Enable='on';
            app.CalculateEmotionButton.Enable='on';
            app.filenameEditField.Enable='on';
            stopperbut(app)
            closePreview(app.cam);
           
        end

        % Button pushed function: SpeechToTextButton
        function SpeechToTextButtonPushed(app, event)
            speechtotext(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop ';
            app.ListenRecordedButton = uibutton(app.UIFigure, 'push');
            app.ListenRecordedButton.ButtonPushedFcn = createCallbackFcn(app, @ListenRecordedButtonPushed, true);
            app.ListenRecordedButton.Enable = 'off';
            app.ListenRecordedButton.Text = 'Listen Recorded';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.CalculateEmotionButton = uibutton(app.UIFigure, 'push');
            app.CalculateEmotionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateEmotionButtonPushed, true);
            app.CalculateEmotionButton.Enable = 'off';
            app.CalculateEmotionButton.Text = 'Calculate Emotion';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Enable = 'off';
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.filenameEditField.Enable = 'off';
            app.EmotionalSpeechTrainerLabel = uilabel(app.UIFigure);
            app.EmotionalSpeechTrainerLabel.Text = 'Emotional Speech Trainer';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.SpeechToTextButton = uibutton(app.UIFigure, 'push');
            app.SpeechToTextButton.ButtonPushedFcn = createCallbackFcn(app, @SpeechToTextButtonPushed, true);
            app.SpeechToTextButton.Text = 'SpeechToText';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ESTrainer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end